classdef ballast_estimate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        PreparingthethrustcommandvaluePanel  matlab.ui.container.Panel
        ThisapplicationisusedtoestimatetheballastcoLabel_8  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel_7  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel_6  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel_5  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel_4  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel_3  matlab.ui.control.Label
        GuidePanel                      matlab.ui.container.Panel
        ThisapplicationisusedtoestimatetheballastcoLabel_2  matlab.ui.control.Label
        ThisapplicationisusedtoestimatetheballastcoLabel  matlab.ui.control.Label
        EstimateTab                     matlab.ui.container.Tab
        FloaterConfigurationTextArea_5  matlab.ui.control.TextArea
        FloaterConfigurationTextAreaLabel_4  matlab.ui.control.Label
        FloaterConfigurationTextArea_4  matlab.ui.control.TextArea
        FloaterConfigurationTextAreaLabel_3  matlab.ui.control.Label
        IterationStatusTextArea         matlab.ui.control.TextArea
        IterationStatusTextAreaLabel    matlab.ui.control.Label
        EstimateBallastConfigurationPanel  matlab.ui.container.Panel
        RESETButton                     matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        DataLoaderPanel                 matlab.ui.container.Panel
        LoadThrustDataLabel             matlab.ui.control.Label
        LOADDATAButton                  matlab.ui.control.Button
        SetParameterPanel               matlab.ui.container.Panel
        DivergenceLimitEditField        matlab.ui.control.NumericEditField
        DivergenceLimitEditFieldLabel   matlab.ui.control.Label
        StallLimitEditField_2           matlab.ui.control.NumericEditField
        StallLimitEditField_2Label      matlab.ui.control.Label
        FitnessToleranceEditField       matlab.ui.control.NumericEditField
        FitnessToleranceEditFieldLabel  matlab.ui.control.Label
        CrossoverRateEditField          matlab.ui.control.NumericEditField
        CrossoverRateEditFieldLabel     matlab.ui.control.Label
        NumberofGenerationsEditField    matlab.ui.control.NumericEditField
        NumberofGenerationsEditFieldLabel  matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        ResultsGuideTab                 matlab.ui.container.Tab
        LocationDescriptionPanel_2      matlab.ui.container.Panel
        Image3_2                        matlab.ui.control.Image
        LocationDescriptionPanel        matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        ResultTab                       matlab.ui.container.Tab
        FloaterConfigurationTextArea_2  matlab.ui.control.TextArea
        FloaterConfigurationTextAreaLabel_2  matlab.ui.control.Label
        FloaterConfigurationTextArea_2  matlab.ui.control.TextArea
        FloaterConfigurationTextAreaLabel_2  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        FloaterConfigurationTextArea    matlab.ui.control.TextArea
        FloaterConfigurationTextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select a CSV file');
            if isequal(file, 0)
                % User canceled the file selection
                app.LoadThrustDataLabel.Text = 'No file selected';
            else
                % Load the selected CSV file
                fullPath = fullfile(path, file);
                app.ThrustData = readmatrix(fullPath);
                app.LoadThrustDataLabel.Text = 'Data loaded successfully';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GuidePanel = uipanel(app.StartTab);
            app.GuidePanel.Title = 'Guide';
            app.ThisapplicationisusedtoestimatetheballastcoLabel = uilabel(app.GuidePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel.Text = 'This application is used to estimate the required ballast configuration for BlueROV to a neutrally buoyant state. ';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_2 = uilabel(app.GuidePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_2.Text = 'This process completely ignores the actual weight and buoyancy of the ROVs: you only need to input body thrusts command value for 6 DOFs from a simple controller.';
            app.PreparingthethrustcommandvaluePanel = uipanel(app.StartTab);
            app.PreparingthethrustcommandvaluePanel.Title = 'Preparing the thrust command value';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_3 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_3.Text = 'First, a simple controller is designed to drive the BlueROV to a certain depth, an maintain the depth position and level orientation for some period of time. A simple reference trajectory with a PID controller can be used for this process. ';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_4 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_4.Text = 'Second, we record the body thrust command from the controller throughout the experiment. We will then divide the data into two phase: Stabilizing Phase and Maintain Phase. ';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_5 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_5.Text = 'Stabilizing Phase is the period needed to drive BlueROV to the desired position and orientation. Maintain Phase is the the period for the BlueROV to maintain the desired position and orientation.';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_6 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_6.Text = 'We split body thrust command data based on the phase. The Maintain Phase data in kept, and stored in a CSV file.';
            app.ThisapplicationisusedtoestimatetheballastcoLabel_7 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_7.Text = {'Expected dimension of the thrust data:'; 'Row      = Maintain Phase timesteps'; 'Column = 6'};
            app.ThisapplicationisusedtoestimatetheballastcoLabel_8 = uilabel(app.PreparingthethrustcommandvaluePanel);
            app.ThisapplicationisusedtoestimatetheballastcoLabel_8.Text = {'Recommended period:'; 'Stabilizing Phase   = 20 second'; 'Maintain Phase      = 40 second'};
            app.Image2 = uiimage(app.StartTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Static_case.jpg');
            app.Image2_2 = uiimage(app.StartTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'demo_thrust.jpg');
            app.EstimateTab = uitab(app.TabGroup);
            app.EstimateTab.Title = 'Estimate';
            app.SetParameterPanel = uipanel(app.EstimateTab);
            app.SetParameterPanel.Title = 'Set Parameter';
            app.PopulationSizeEditFieldLabel = uilabel(app.SetParameterPanel);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.SetParameterPanel, 'numeric');
            app.PopulationSizeEditField.Value = 2500;
            app.NumberofGenerationsEditFieldLabel = uilabel(app.SetParameterPanel);
            app.NumberofGenerationsEditFieldLabel.Text = 'Number of Generations';
            app.NumberofGenerationsEditField = uieditfield(app.SetParameterPanel, 'numeric');
            app.NumberofGenerationsEditField.Value = 150;
            app.CrossoverRateEditFieldLabel = uilabel(app.SetParameterPanel);
            app.CrossoverRateEditFieldLabel.Text = 'Crossover Rate';
            app.CrossoverRateEditField = uieditfield(app.SetParameterPanel, 'numeric');
            app.CrossoverRateEditField.Value = 0.75;
            app.FitnessToleranceEditFieldLabel = uilabel(app.SetParameterPanel);
            app.FitnessToleranceEditFieldLabel.Text = 'Fitness Tolerance';
            app.FitnessToleranceEditField = uieditfield(app.SetParameterPanel, 'numeric');
            app.FitnessToleranceEditField.Value = 0.075;
            app.StallLimitEditField_2Label = uilabel(app.SetParameterPanel);
            app.StallLimitEditField_2Label.Text = 'Stall Limit';
            app.StallLimitEditField_2 = uieditfield(app.SetParameterPanel, 'numeric');
            app.StallLimitEditField_2.Value = 15;
            app.DivergenceLimitEditFieldLabel = uilabel(app.SetParameterPanel);
            app.DivergenceLimitEditFieldLabel.Text = 'Divergence Limit';
            app.DivergenceLimitEditField = uieditfield(app.SetParameterPanel, 'numeric');
            app.DivergenceLimitEditField.Value = 30;
            app.DataLoaderPanel = uipanel(app.EstimateTab);
            app.DataLoaderPanel.Title = 'Data Loader';
            app.LOADDATAButton = uibutton(app.DataLoaderPanel, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Text = 'LOAD DATA';
            app.LoadThrustDataLabel = uilabel(app.DataLoaderPanel);
            app.LoadThrustDataLabel.Text = 'Load Thrust Data';
            app.EstimateBallastConfigurationPanel = uipanel(app.EstimateTab);
            app.EstimateBallastConfigurationPanel.Title = 'Estimate Ballast Configuration';
            app.RUNButton = uibutton(app.EstimateBallastConfigurationPanel, 'push');
            app.RUNButton.Text = 'RUN';
            app.RESETButton = uibutton(app.EstimateBallastConfigurationPanel, 'push');
            app.RESETButton.Text = 'RESET';
            app.IterationStatusTextAreaLabel = uilabel(app.EstimateTab);
            app.IterationStatusTextAreaLabel.Text = 'Iteration Status';
            app.IterationStatusTextArea = uitextarea(app.EstimateTab);
            app.FloaterConfigurationTextAreaLabel_3 = uilabel(app.EstimateTab);
            app.FloaterConfigurationTextAreaLabel_3.Text = 'Residual Results';
            app.FloaterConfigurationTextArea_4 = uitextarea(app.EstimateTab);
            app.FloaterConfigurationTextAreaLabel_4 = uilabel(app.EstimateTab);
            app.FloaterConfigurationTextAreaLabel_4.Text = '';
            app.FloaterConfigurationTextArea_5 = uitextarea(app.EstimateTab);
            app.ResultsGuideTab = uitab(app.TabGroup);
            app.ResultsGuideTab.Title = 'Results Guide';
            app.LocationDescriptionPanel = uipanel(app.ResultsGuideTab);
            app.LocationDescriptionPanel.Title = 'Location Description';
            app.Image3 = uiimage(app.LocationDescriptionPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Loc.jpg');
            app.LocationDescriptionPanel_2 = uipanel(app.ResultsGuideTab);
            app.LocationDescriptionPanel_2.Title = 'Location Description';
            app.Image3_2 = uiimage(app.LocationDescriptionPanel_2);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'cond.jpg');
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.FloaterConfigurationTextAreaLabel = uilabel(app.ResultTab);
            app.FloaterConfigurationTextAreaLabel.Text = 'Floater Configuration';
            app.FloaterConfigurationTextArea = uitextarea(app.ResultTab);
            app.Image = uiimage(app.ResultTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Ballast_Config_Left.jpg');
            app.Image_2 = uiimage(app.ResultTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Ballast_Config_Right.jpg');
            app.Image_3 = uiimage(app.ResultTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Ballast_Config_Weight.jpg');
            app.FloaterConfigurationTextAreaLabel_2 = uilabel(app.ResultTab);
            app.FloaterConfigurationTextAreaLabel_2.Text = 'Weight Configuration';
            app.FloaterConfigurationTextArea_2 = uitextarea(app.ResultTab);
            app.FloaterConfigurationTextAreaLabel_2 = uilabel(app.ResultTab);
            app.FloaterConfigurationTextAreaLabel_2.Text = '';
            app.FloaterConfigurationTextArea_2 = uitextarea(app.ResultTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ballast_estimate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end